classdef hex < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ButtonGroup    matlab.ui.container.ButtonGroup
        RadioButton    matlab.ui.control.RadioButton
        RadioButton2   matlab.ui.control.RadioButton
        RadioButton3   matlab.ui.control.RadioButton
        LabelSlider    matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        LabelListBox   matlab.ui.control.Label
        ListBox        matlab.ui.control.ListBox
        LabelListBox2  matlab.ui.control.Label
        ListBox2       matlab.ui.control.ListBox
        LabelSwitch    matlab.ui.control.Label
        Switch         matlab.ui.control.Switch
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        count_vect % Description
        dates % Description
        gdeath % Description
        gcases % Description
        deatht%table of all death per country
        casest%table of all cases per country
        country_list % list of all countries
        
    end
    
    methods (Access = private)
        
        function list = list_state(app,name)%return a char vector of names of states om a country
            lm=length(app.count_vect);
            head=[];
            i=0;
            for ii=1:lm
                if strcmp(app.count_vect{ii}.name,name)
                    head=app.count_vect{ii}.head;
                    last=head;
                    cur=head;
                    while ~isempty(cur)
                        if ~isempty(cur.name)
                            i=i+1;
                        end
                        last=cur;
                        cur=cur.nxt;
                    end
                end
            end
            if i==0
                list=cell(1,1);
                list{1,1}='All';
            else
                list=cell(1,i+1)
                k=1;
                list{1,1}='All';
                last=head;
                cur=head;
                while ~isempty(cur)
                    if ~isempty(cur.name)
                        k=k+1;
                        list{1,k}=cur.name;
                    end
                    last=cur;
                    cur=cur.nxt;
                end
            end
            
        end
        
        function [dea,cas] = get_data(app,country,state)
            if strcmp(country,'Global')
                dea=app.gdeath;
                cas=app.gcases;
                return;
            end
            lm=length(app.count_vect);
            head=[];
            for ii=1:lm
                if strcmp(app.count_vect{ii}.name,country)
                    head=app.count_vect{ii};
                end
            end
            if strcmp(state,'All')
                dea=head.death;
                cas=head.cases;
                return;
            else
                head=head.head;
                last=head;
                cur=head;
                while ~isempty(cur)
                    if strcmp(cur.name,state)
                        dea=cur.death;
                        cas=cur.cases;
                    end
                    last=cur;
                    cur=cur.nxt;
                end
            end
        end
        
        function plotter(app,d,c)
            format long
            
            cn=app.ListBox.Value;
            sn=append(app.ListBox2.Value,',');
            dl=app.Switch.Value;
            db=app.RadioButton.Value;
            cb=app.RadioButton2.Value;
            bb=app.RadioButton3.Value;
            if strcmp(sn,'All,')
            sn='';
            end
            
            if cb
                st=sprintf('%s number of cases in %s%s',dl,sn,cn);
            elseif db
                st=sprintf('%s number of deaths in %s%s',dl,sn,cn);
            elseif bb
                st=sprintf('%s number of cases and deaths in %s%s',dl,sn,cn);
            end
            
            cla(app.UIAxes,'reset');
            if strcmp(app.Switch.Value,'cummulative')
                [d,c]=app.mean(d,c);
            end
            dt=app.RadioButton.Value;
            ct=app.RadioButton2.Value;
            bt=app.RadioButton3.Value;
            if dt
                p=bar(app.UIAxes,app.dates,d);
                ax=ancestor(p,'axes');
                ax.YAxis.Exponent=0;
                %                  YLim(app.UIAxes,[min(d),max(d)]);
            end
            if ct
                p=bar(app.UIAxes,app.dates,c);
                ax=ancestor(p,'axes')
                %                 YLim(app.UIAxes,[min(c),max(c)]);
                ax.YAxis.Exponent=0;
            end
            if bt
                p=bar(app.UIAxes,app.dates,c);
                hold(app.UIAxes,'on');
                ax=ancestor(p,'axes');
                ax.YAxis.Exponent=0;
                yyaxis(app.UIAxes,'right')
                p=plot(app.UIAxes,app.dates,d);
                
                ylim(app.UIAxes,[min(d),max(d)]);
                
            end
            datetick(app.UIAxes,'x','dd/mm/yyyy','keepticks')
            
            title(app.UIAxes,st);
            
            hold(app.UIAxes,'off');
        end
        
        function results = current_count(app,name)
            if strcmp(name,'Global')
                results='All';
                return;
            end
            lm=length(app.count_vect)
            for ii=1:lm
                if strcmp(app.count_vect{ii}.name,name)
                    results=app.count_vect{ii};
                end
            end
        end
        
        function [d,c] = mean(app,d,c)
            if strcmp(app.Switch.Value,'daily')
                return;
            end
            value=fix(app.Slider.Value);
            k=value;
            lm=length(d);
            d1=movmean(d,k);
            c1=movmean(c,k);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format long;
            load data_project.mat;
            [app.count_vect,app.deatht,app.casest,app.dates,app.gdeath,app.gcases,list]=earth(covid_data);
            app.dates=datenum(app.dates);
            p=bar(app.UIAxes,app.dates,app.gcases);
            datetick(app.UIAxes,'x','dd/mm/yyyy','keepticks');
            ax=ancestor(p,'axes');
            ax.YAxis.Exponent=0;
            hold(app.UIAxes,'on');
            ls=cell(1,length(list)+1);
            ls{1,1}='Global';
            ls(1,2:end)=list(1,1:end);
            app.ListBox.Items=ls;
            app.ListBox2.Items={'All'};
            yyaxis(app.UIAxes,'right');
            p=plot(app.UIAxes,app.dates,app.gdeath);
            hold(app.UIAxes,'off');
            cn=app.ListBox.Value;
            sn=append(app.ListBox2.Value,',');
            dl=app.Switch.Value;
            db=app.RadioButton.Value;
            cb=app.RadioButton2.Value;
            bb=app.RadioButton3.Value;
            if strcmp(sn,'All,')
                sn='';
            end
            st=sprintf('%s number of cases and deaths in %s%s',dl,sn,cn);
            title(app.UIAxes,st);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            list=app.list_state(value);
            app.ListBox2.Items=(list);
            c=app.current_count(value);
            if strcmp(value,'Global')
                x=app.gdeath;
                y=app.gcases;
                if strcmp(app.Switch.Value,'cummulative')
                    [x,y]=app.mean(x,y);
                end
                app.plotter(x,y);
            else
                x=c.death;
                y=c.cases;
                if strcmp(app.Switch.Value,'cummulative')
                    [x,y]=app.mean(c.death,c.cases);
                    app.plotter(x,y);
                end
                
            end
            
        end

        % Value changed function: ListBox2
        function ListBox2ValueChanged(app, event)
            state = app.ListBox2.Value;
            country=app.ListBox.Value;
            [d,c]=app.get_data(country,state);
            if strcmp(app.Switch.Value,'cummulative')
                [d,c]=app.mean(d,c);
            end
            app.plotter(d,c);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            country=app.ListBox.Value;
            state=app.ListBox2.Value;
            [d,c]=app.get_data(country,state);
            if strcmp(app.Switch.Value,'cummulative')
                [d,c]=app.mean(d,c);
            end
            app.plotter(d,c);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if strcmp(app.Switch.Value,'daily')
                app.Slider.Value=1;
                return;
            end
            value =fix(app.Slider.Value);
            country=app.ListBox.Value;
            state=app.ListBox2.Value;
            [d,c]=app.get_data(country,state);
            d=movmean(d,value);
            c=movmean(c,value);
            app.plotter(d,c);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            avg_sig = app.Switch.Value;
            value =fix(app.Slider.Value);
            country=app.ListBox.Value;
            state=app.ListBox2.Value;
            [d,c]=app.get_data(country,state);
            if strcmp(avg_sig,'daily')
                app.Slider.Value=1;
                app.plotter(d,c);
            else
                [d,c]=app.mean(d,c);
                app.plotter(d,c);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Data plot';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Deaths';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Cases';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup);
            app.RadioButton3.Text = 'Both';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Average';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 15];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.LabelListBox = uilabel(app.UIFigure);
            app.LabelListBox.Text = 'Country';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.LabelListBox2 = uilabel(app.UIFigure);
            app.LabelListBox2.Text = 'State or region';
            app.ListBox2 = uilistbox(app.UIFigure);
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox2ValueChanged, true);
            app.LabelSwitch = uilabel(app.UIFigure);
            app.LabelSwitch.Text = ' ';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'cummulative', 'daily'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'cummulative';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hex

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end